classdef T9_2022 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        CallButton                      matlab.ui.control.Button
        TextButton                      matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        SomeonetoListenButton           matlab.ui.control.Button
        AdviceButton                    matlab.ui.control.Button
        DistractionButton               matlab.ui.control.Button
        ImnotsureButton                 matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        Tab_3                           matlab.ui.container.Tab
        HowsafedoyoufeelrightnowLabel   matlab.ui.control.Label
        HowsafedoyoufeelrightnowSlider  matlab.ui.control.Slider
        TextArea_2                      matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
 %%%%STUPID CODE WONT LET ME DELETE FUCKER
        end

        % Button pushed function: CallButton
        function CallButtonPushed(app, event)
      close all
      clc
        
        pause(0.1);
            app.CallButton.BackgroundColor=[0.94,0.72,0.41];
            app.CallButton.Text='Redirecting...';
            
            pause(2)
            app.TabGroup.SelectedTab=app.Tab;
            
        end

        % Button pushed function: TextButton
        function TextButtonPushed(app, event)
            close all 
            clc
        
        pause(0.1);
            app.TextButton.BackgroundColor=[0.94,0.72,0.41];
            app.TextButton.Text='Redirecting...';
            
            pause(2)
            app.TabGroup.SelectedTab=app.Tab;
            
        end

        % Callback function
        function WhatkindofhelpwouldyoulikeButtonGroupSelectionChanged(app, event)
%%%deleted useless pos
        end

        % Button pushed function: SomeonetoListenButton
        function SomeonetoListenButtonPushed(app, event)
            pause(0.2);
            app.SomeonetoListenButton.BackgroundColor=[0.94,0.72,0.41];
            fprintf('Someone will be here to listen to you shortly...')
            
            pause(0.3);
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Button pushed function: AdviceButton
        function AdviceButtonPushed(app, event)
              pause(0.2);
            app.AdviceButton.BackgroundColor=[0.94,0.72,0.41];
            fprintf('A verfied therapist/psychologist will be with you shortly...\n ')
            
             pause(0.3);
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Button pushed function: DistractionButton
        function DistractionButtonPushed(app, event)
              pause(0.2);
            app.DistractionButton.BackgroundColor=[0.94,0.72,0.41];
            fprintf('Memes incoming... \n')
            
             pause(0.3);
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Button pushed function: ImnotsureButton
        function ImnotsureButtonPushed(app, event)
              pause(0.2);
            app.ImnotsureButton.BackgroundColor=[0.94,0.72,0.41];
            fprintf("That's ok! An operator will be on the line soon to help you through it regardless! \n")
            
             pause(0.2);
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Value changing function: HowsafedoyoufeelrightnowSlider
        function HowsafedoyoufeelrightnowSliderValueChanging(app, event)
       %%%GARBAGE 
        end

        % Value changed function: HowsafedoyoufeelrightnowSlider
        function HowsafedoyoufeelrightnowSliderValueChanged(app, event)
            value = app.HowsafedoyoufeelrightnowSlider.Value;
                  changingValue = event.Value;
            changingValue=round(changingValue);
            fprintf("You rated yourself as a %0.0f out of 10, thank you for telling us \n",changingValue);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.TextArea = uitextarea(app.Tab_2);
            app.TextArea.Value = {'Which form of communication would you be most comfortable with?'};
            app.CallButton = uibutton(app.Tab_2, 'push');
            app.CallButton.ButtonPushedFcn = createCallbackFcn(app, @CallButtonPushed, true);
            app.CallButton.Text = 'Call';
            app.TextButton = uibutton(app.Tab_2, 'push');
            app.TextButton.ButtonPushedFcn = createCallbackFcn(app, @TextButtonPushed, true);
            app.TextButton.Text = 'Text';
            app.Tab = uitab(app.TabGroup);
            app.SomeonetoListenButton = uibutton(app.Tab, 'push');
            app.SomeonetoListenButton.ButtonPushedFcn = createCallbackFcn(app, @SomeonetoListenButtonPushed, true);
            app.SomeonetoListenButton.Text = 'Someone to Listen';
            app.AdviceButton = uibutton(app.Tab, 'push');
            app.AdviceButton.ButtonPushedFcn = createCallbackFcn(app, @AdviceButtonPushed, true);
            app.AdviceButton.Text = 'Advice';
            app.DistractionButton = uibutton(app.Tab, 'push');
            app.DistractionButton.ButtonPushedFcn = createCallbackFcn(app, @DistractionButtonPushed, true);
            app.DistractionButton.Text = 'Distraction';
            app.ImnotsureButton = uibutton(app.Tab, 'push');
            app.ImnotsureButton.ButtonPushedFcn = createCallbackFcn(app, @ImnotsureButtonPushed, true);
            app.ImnotsureButton.Text = 'I''m not sure';
            app.TextArea_3 = uitextarea(app.Tab);
            app.TextArea_3.Value = {'What kind of help would you like?'};
            app.Tab_3 = uitab(app.TabGroup);
            app.HowsafedoyoufeelrightnowLabel = uilabel(app.Tab_3);
            app.HowsafedoyoufeelrightnowLabel.Text = {'How safe do you feel right now?'; ''};
            app.HowsafedoyoufeelrightnowSlider = uislider(app.Tab_3);
            app.HowsafedoyoufeelrightnowSlider.Limits = [0 10];
            app.HowsafedoyoufeelrightnowSlider.ValueChangedFcn = createCallbackFcn(app, @HowsafedoyoufeelrightnowSliderValueChanged, true);
            app.HowsafedoyoufeelrightnowSlider.ValueChangingFcn = createCallbackFcn(app, @HowsafedoyoufeelrightnowSliderValueChanging, true);
            app.TextArea_2 = uitextarea(app.Tab_3);
            app.TextArea_2.Value = {'Please rate on a scale of 0-10. 0 means you feel the need to be hospitlized immediately, 10 being you are confident you will not harm yourself or others.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T9_2022

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end